classdef MagneticFieldCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        InputsTab                    matlab.ui.container.Tab
        Circuit1                     matlab.ui.container.Panel
        XEditField_2Label            matlab.ui.control.Label
        X1                           matlab.ui.control.NumericEditField
        YEditField_2Label            matlab.ui.control.Label
        Y1                           matlab.ui.control.NumericEditField
        Conductor1Label              matlab.ui.control.Label
        Conductor2Label              matlab.ui.control.Label
        X_A_2Label                   matlab.ui.control.Label
        X2                           matlab.ui.control.NumericEditField
        Y_A_2Label                   matlab.ui.control.Label
        Y2                           matlab.ui.control.NumericEditField
        X2_2Label                    matlab.ui.control.Label
        X3                           matlab.ui.control.NumericEditField
        Conductor3Label              matlab.ui.control.Label
        Y2_2Label                    matlab.ui.control.Label
        Y3                           matlab.ui.control.NumericEditField
        LineCurrentEditField_2Label  matlab.ui.control.Label
        LineCurrent1                 matlab.ui.control.NumericEditField
        CalculateButton              matlab.ui.control.Button
        MetersfromCenterlineEditFieldLabel  matlab.ui.control.Label
        HorizontalRange              matlab.ui.control.NumericEditField
        Circuit2                     matlab.ui.container.Panel
        LineCurrentA_2Label          matlab.ui.control.Label
        LineCurrent2                 matlab.ui.control.NumericEditField
        X1_2Label                    matlab.ui.control.Label
        X1_2                         matlab.ui.control.NumericEditField
        Y1_2Label                    matlab.ui.control.Label
        Y1_2                         matlab.ui.control.NumericEditField
        Conductor1Label_2            matlab.ui.control.Label
        Conductor2Label_2            matlab.ui.control.Label
        X2_2Label_2                  matlab.ui.control.Label
        X2_2                         matlab.ui.control.NumericEditField
        Y2_2Label_2                  matlab.ui.control.Label
        Y2_2                         matlab.ui.control.NumericEditField
        X3_2Label                    matlab.ui.control.Label
        X3_2                         matlab.ui.control.NumericEditField
        Conductor3Label_2            matlab.ui.control.Label
        Y3_2Label                    matlab.ui.control.Label
        Y3_2                         matlab.ui.control.NumericEditField
        CircuitTwoEnabled            matlab.ui.control.CheckBox
        TowerTypeDropDownLabel       matlab.ui.control.Label
        TowerTypeDropDown            matlab.ui.control.DropDown
        PhasingDropDownLabel         matlab.ui.control.Label
        PhasingDropDown              matlab.ui.control.DropDown
        HeightAboveGroundEditFieldLabel  matlab.ui.control.Label
        Height                       matlab.ui.control.NumericEditField
        TowerConfigGraph             matlab.ui.control.UIAxes
        MSDEditFieldLabel            matlab.ui.control.Label
        MSD                          matlab.ui.control.NumericEditField
        MagFieldTab                  matlab.ui.container.Tab
        MagneticFluxGraph            matlab.ui.control.UIAxes
        MinimumSafeDistanceTab       matlab.ui.container.Tab
        MinimumMagFieldCrossSection  matlab.ui.control.UIAxes
    end


    properties (Access = private)

        Bxin % In phase x component of mag field
        Byin % In phase y component of mag field
        Bxout %Out of phase x component
        Byout %Out of phase y component
        
        BMinAtMSD %Minimum resultant mag field at the minimum safe distance
        
        Phase1_1 = 2*pi/3;
        Phase2_1 = 0;
        Phase3_1 = -2*pi/3;
        Phase1_2 = 2*pi/3;
        Phase2_2 = 0;
        Phase3_2 = -2*pi/3;
        
    end

    methods (Access = private)
    
        function Btot = MagneticFluxMagnitude(app, xPos, yPos)
            %populate vectors with values for conductors 1 through 6
            %this is a work around because classes and structs seem to be forbidden in app designer
            
            CurrentVector = [app.LineCurrent1.Value, app.LineCurrent1.Value, app.LineCurrent1.Value, app.LineCurrent2.Value, app.LineCurrent2.Value, app.LineCurrent2.Value];
            PhaseVector = [app.Phase1_1, app.Phase2_1, app.Phase3_1, app.Phase1_2, app.Phase2_2, app.Phase3_2];
            XVector = [xPos-app.X1.Value, xPos-app.X2.Value, xPos-app.X3.Value, xPos-app.X1_2.Value, xPos-app.X2_2.Value, xPos-app.X3_2.Value];
            YVector = [yPos-app.Y1.Value, yPos-app.Y2.Value, yPos-app.Y3.Value, yPos-app.Y1_2.Value, yPos-app.Y2_2.Value, yPos-app.Y3_2.Value];
            
            for n = 1:6
               app.Bxin(n) =  MagneticFluxXInPhase(app, CurrentVector(n), PhaseVector(n), XVector(n), YVector(n));
               app.Byin(n) =  MagneticFluxYInPhase(app, CurrentVector(n), PhaseVector(n), XVector(n), YVector(n));
               app.Bxout(n) =  MagneticFluxXOutPhase(app, CurrentVector(n), PhaseVector(n), XVector(n), YVector(n));
               app.Byout(n) =  MagneticFluxYOutPhase(app, CurrentVector(n), PhaseVector(n), XVector(n), YVector(n));
            end
            
            Btot = sqrt(sum(app.Bxin)^2 + sum(app.Byin)^2 + sum(app.Bxout)^2 + sum(app.Byout)^2);
         end
        
         function val = MagneticFluxYInPhase(~, I, phase, X, Y)
            Iphase = I*cos(phase);
            permeability = 4*pi*10.^-7;
            
            val = (permeability * X * Iphase)/(2*pi*(X^2 + Y^2));
        end
        
        function val = MagneticFluxXInPhase(~, I, phase, X, Y)
            Iphase = I*cos(phase);
            permeability = 4*pi*10^-7;
            
            val = -(permeability * Y * Iphase)/(2*pi*(X^2 + Y^2));
        end
        
        function val = MagneticFluxXOutPhase(~, I, phase, X, Y)
            Iphase = I*sin(phase);
            permeability = 4*pi*10^-7;
            
            val = -(permeability * Y * Iphase)/(2*pi*(X^2 + Y^2));
        end
        
        function val = MagneticFluxYOutPhase(~, I, phase, X, Y)
            Iphase = I*sin(phase);
            permeability = 4*pi*10^-7;
            
            val = (permeability * X * Iphase)/(2*pi*(X^2 + Y^2));
        end
        
        function PlotTowerConfig(app)
            cla(app.TowerConfigGraph);
            hold(app.TowerConfigGraph, 'on');
            plot(app.TowerConfigGraph, [-10, 10], [app.Height.Value app.Height.Value]);
            plot(app.TowerConfigGraph, app.X1.Value, app.Y1.Value, ColourFromPhase(app, app.Phase1_1));
            plot(app.TowerConfigGraph, app.X2.Value, app.Y2.Value, ColourFromPhase(app, app.Phase2_1));
            plot(app.TowerConfigGraph, app.X3.Value, app.Y3.Value, ColourFromPhase(app, app.Phase3_1));
            if(app.CircuitTwoEnabled.Value)
                plot(app.TowerConfigGraph, app.X1_2.Value, app.Y1_2.Value, ColourFromPhase(app, app.Phase1_2));
                plot(app.TowerConfigGraph, app.X2_2.Value, app.Y2_2.Value, ColourFromPhase(app, app.Phase2_2));
                plot(app.TowerConfigGraph, app.X3_2.Value, app.Y3_2.Value, ColourFromPhase(app, app.Phase3_2));
            end
            PlotMinimumMagFieldCrossSection(app);
        end
        
        function PlotMinimumMagFieldCrossSection(app)
            cla(app.MinimumMagFieldCrossSection);
            hold(app.MinimumMagFieldCrossSection, 'on');
            plot(app.MinimumMagFieldCrossSection, [-100, 100], [app.Height.Value app.Height.Value]);
            plot(app.MinimumMagFieldCrossSection, app.X1.Value, app.Y1.Value, ColourFromPhase(app, app.Phase1_1));
            PlotCircleAtMSD(app, app.X1.Value, app.Y1.Value);
            plot(app.MinimumMagFieldCrossSection, app.X2.Value, app.Y2.Value, ColourFromPhase(app, app.Phase2_1));
            PlotCircleAtMSD(app, app.X2.Value, app.Y2.Value);
            plot(app.MinimumMagFieldCrossSection, app.X3.Value, app.Y3.Value, ColourFromPhase(app, app.Phase3_1));
            PlotCircleAtMSD(app, app.X3.Value, app.Y3.Value);
            if(app.CircuitTwoEnabled.Value)
                plot(app.MinimumMagFieldCrossSection, app.X1_2.Value, app.Y1_2.Value, ColourFromPhase(app, app.Phase1_2));
                PlotCircleAtMSD(app, app.X1_2.Value, app.Y1_2.Value);
                plot(app.MinimumMagFieldCrossSection, app.X2_2.Value, app.Y2_2.Value, ColourFromPhase(app, app.Phase2_2));
                PlotCircleAtMSD(app, app.X2_2.Value, app.Y2_2.Value);
                plot(app.MinimumMagFieldCrossSection, app.X3_2.Value, app.Y3_2.Value, ColourFromPhase(app, app.Phase3_2));
                PlotCircleAtMSD(app, app.X3_2.Value, app.Y3_2.Value);
            end
        end
        
        function PlotCircleAtMSD(app, xPos, yPos)
            hold(app.MinimumMagFieldCrossSection, 'on');
            angle = 0:pi/50:2*pi;
            xUnit = app.MSD.Value * cos(angle) + xPos;
            yUnit = app.MSD.Value * sin(angle) + yPos;
            plot(app.MinimumMagFieldCrossSection, xUnit, yUnit, 'k');
        end
        
        function colour = ColourFromPhase(~, phase)
            if(phase == 2*pi/3)
                colour = 'b*';
            end
            if(phase == 0)
                colour = 'g*';
            end
            if(phase == -2*pi/3)
                colour = 'r*';
            end
        end
        
        function MakePositionsUneditable(app)
            app.X1.Editable = 'off';
            app.X2.Editable = 'off';
            app.X3.Editable = 'off';
            app.Y1.Editable = 'off';
            app.Y2.Editable = 'off';
            app.Y3.Editable = 'off';
            app.X1_2.Editable = 'off';
            app.X2_2.Editable = 'off';
            app.X3_2.Editable = 'off';
            app.Y1_2.Editable = 'off';
            app.Y2_2.Editable = 'off';
            app.Y3_2.Editable = 'off';
            
        end
        
        function SetPositionsTo240TypeLNoExtension(app)
            app.X1.Value = -3.96;
            app.X2.Value = -5.48;
            app.X3.Value = -3.96;
            app.Y1.Value = 20.57;
            app.Y2.Value = 26.67;
            app.Y3.Value = 32.76;
            app.X1_2.Value = 3.96;
            app.X2_2.Value = 5.48;
            app.X3_2.Value = 3.96;
            app.Y1_2.Value = 20.57;
            app.Y2_2.Value = 26.67;
            app.Y3_2.Value = 32.76;
        end
        
        function SetPositionsTo138Tangent19m(app)
            app.X1.Value = -2.13;
            app.X2.Value = -2.13;
            app.X3.Value = 2.13;
            app.Y1.Value = 12.62;
            app.Y2.Value = 15.72;
            app.Y3.Value = 15.72;
            app.X1_2.Value = 100.0;
            app.X2_2.Value = 100.0;
            app.X3_2.Value = 100.0;
            app.Y1_2.Value = 100.0;
            app.Y2_2.Value = 100.0;
            app.Y3_2.Value = 100.0;
        end
        
        function MakePositionsEditable(app)
            app.X1.Editable = 'on';
            app.X2.Editable = 'on';
            app.X3.Editable = 'on';
            app.Y1.Editable = 'on';
            app.Y2.Editable = 'on';
            app.Y3.Editable = 'on';
            app.X1_2.Editable = 'on';
            app.X2_2.Editable = 'on';
            app.X3_2.Editable = 'on';
            app.Y1_2.Editable = 'on';
            app.Y2_2.Editable = 'on';
            app.Y3_2.Editable = 'on';
        end
        
        function MinimumMagFieldAtMSD(app)
            app.BMinAtMSD = MagneticFluxMagnitude(app, app.MSD.Value * cos(pi) + app.X1.Value, app.MSD.Value * sin(pi) + app.Y1.Value);
            MinimumMagFieldAroundSingleConductorAtMSD(app, app.X1.Value, app.Y1.Value, 2*pi/3, 5.63);
            %MinimumMagFieldAroundSingleConductorAtMSD(app, app.X2.Value, app.Y2.Value);
            %MinimumMagFieldAroundSingleConductorAtMSD(app, app.X3.Value, app.Y3.Value);
            
            if(app.CircuitTwoEnabled.Value)
                %MinimumMagFieldAroundSingleConductorAtMSD(app, app.X1_2.Value, app.Y1_2.Value);
                %MinimumMagFieldAroundSingleConductorAtMSD(app, app.X2_2.Value, app.Y2_2.Value);
                %MinimumMagFieldAroundSingleConductorAtMSD(app, app.X3_2.Value, app.Y3_2.Value);
            end
        end
        
        function MinimumMagFieldAroundSingleConductorAtMSD(app, xPos, yPos, startAngle, endAngle)
            angle = linspace(startAngle, endAngle, 1000);
            
            for n = 1:1000
                B = MagneticFluxMagnitude(app, app.MSD.Value * cos(angle(n)) + xPos, app.MSD.Value * sin(angle(n)) + yPos);
                if (B < app.BMinAtMSD)
                    app.BMinAtMSD = B;
                end
            end  
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            MakePositionsUneditable(app);
            SetPositionsTo240TypeLNoExtension(app);
            PlotTowerConfig(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            Btot= zeros(1,4000);
            
            xPosition = linspace(-app.HorizontalRange.Value, app.HorizontalRange.Value, 4000);
            
            for n = 1:4000
                Btot(n) = MagneticFluxMagnitude(app, xPosition(n), app.Height.Value);
            end
            app.MagneticFluxGraph.YAxisLocation = 'origin';
            plot(app.MagneticFluxGraph, xPosition, Btot);
            
            xPosition = linspace(-30, 30, 250);
            yPosition = linspace(0, 50, 250);
            
            MinimumMagFieldAtMSD(app);
            
            PlotTowerConfig(app);
            for i = 1:250
                for j = 1:250
                    Bmag = MagneticFluxMagnitude(app, xPosition(i), yPosition(j));
                    if(Bmag >= app.BMinAtMSD && Bmag <= app.BMinAtMSD*1.05 )
                        plot(app.TowerConfigGraph, xPosition(i), yPosition(j), 'c.', 'MarkerSize', 5);
                        plot(app.MinimumMagFieldCrossSection, xPosition(i), yPosition(j), 'c.', 'MarkerSize', 5);
                    end
                end
            end
        end

        % Value changed function: X1
        function X1_1Changed(app, event)
            PlotTowerConfig(app);
        end

        % Value changed function: Y1
        function Y1_1Changed(app, event)
            PlotTowerConfig(app);
        end

        % Value changed function: X2
        function X1_2Changed(app, event)
            PlotTowerConfig(app);
        end

        % Value changed function: Y2
        function Y1_2Changed(app, event)
            PlotTowerConfig(app);
        end

        % Value changed function: X3
        function X1_3Changed(app, event)
            PlotTowerConfig(app);
        end

        % Value changed function: Y3
        function Y1_3Changed(app, event)
            PlotTowerConfig(app);
        end

        % Value changed function: X1_2
        function X2_1Changed(app, event)
            PlotTowerConfig(app);
        end

        % Value changed function: Y1_2
        function Y2_1Changed(app, event)
            PlotTowerConfig(app);
        end

        % Value changed function: X2_2
        function X2_2Changed(app, event)
            PlotTowerConfig(app);
        end

        % Value changed function: Y2_2
        function Y2_2Changed(app, event)
            PlotTowerConfig(app);
        end

        % Value changed function: X3_2
        function X2_3Changed(app, event)
            PlotTowerConfig(app);
        end

        % Value changed function: Y3_2
        function Y2_3Changed(app, event)
            PlotTowerConfig(app);
        end

        % Value changed function: Height
        function HeightAboveGroudChanged(app, event)
            PlotTowerConfig(app);
        end

        % Value changed function: PhasingDropDown
        function PhasingChanged(app, event)
            value = app.PhasingDropDown.Value;
            if value == 'rgb - rgb'
                app.Phase1_1 = 2*pi/3;
                app.Phase2_1 = 0;
                app.Phase3_1 = -2*pi/3;
                app.Phase1_2 = 2*pi/3;
                app.Phase2_2 = 0;
                app.Phase3_2 = -2*pi/3;
            end
            if value == 'rgb - bgr'
                app.Phase1_1 = 2*pi/3;
                app.Phase2_1 = 0;
                app.Phase3_1 = -2*pi/3;
                app.Phase1_2 = -2*pi/3;
                app.Phase2_2 = 0;
                app.Phase3_2 = 2*pi/3;               
            end
            if value == 'rgb - rbg'
                app.Phase1_1 = 2*pi/3;
                app.Phase2_1 = 0;
                app.Phase3_1 = -2*pi/3;
                app.Phase1_2 = 0;
                app.Phase2_2 = 2*pi/3;
                app.Phase3_2 = -2*pi/3;  
            end
            if value == 'rgb - brg'
                app.Phase1_1 = 2*pi/3;
                app.Phase2_1 = 0;
                app.Phase3_1 = -2*pi/3;
                app.Phase1_2 = 0;
                app.Phase2_2 = -2*pi/3;
                app.Phase3_2 = 2*pi/3;             
            end
            if value == 'rgb - gbr'
                app.Phase1_1 = 2*pi/3;
                app.Phase2_1 = 0;
                app.Phase3_1 = -2*pi/3;
                app.Phase1_2 = -2*pi/3;
                app.Phase2_2 = 2*pi/3;
                app.Phase3_2 = 0;  
            end
            if value == 'rgb - grb'
                app.Phase1_1 = 2*pi/3;
                app.Phase2_1 = 0;
                app.Phase3_1 = -2*pi/3;
                app.Phase1_2 = 2*pi/3;
                app.Phase2_2 = -2*pi/3;
                app.Phase3_2 = 0;              
            end
        
            PlotTowerConfig(app)
        end

        % Value changed function: TowerTypeDropDown
        function TowerTypeChanged(app, event)
            value = app.TowerTypeDropDown.Value;
            if strcmp(value, '240kV Type L No Extension')
                MakePositionsUneditable(app);
                SetPositionsTo240TypeLNoExtension(app);
                app.LineCurrent2.Editable = 'on';
                PlotTowerConfig(app);
            end
            if strcmp(value, '138kV Tangent 19m pole')
                MakePositionsUneditable(app);
                SetPositionsTo138Tangent19m(app);
                app.LineCurrent2.Value = 0.0;
                app.LineCurrent2.Editable = 'off';
                PlotTowerConfig(app);
            end
            if strcmp(value,'Custom')
                MakePositionsEditable(app);
                app.LineCurrent2.Editable = 'on';
            end
        end

        % Value changed function: CircuitTwoEnabled
        function CircuitTwoEnabledChanged(app, event)
            PlotTowerConfig(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.InputsTab.Units = 'pixels';
            app.Circuit1 = uipanel(app.InputsTab);
            app.Circuit1.Title = 'Circuit One';
            app.XEditField_2Label = uilabel(app.Circuit1);
            app.XEditField_2Label.Text = 'X';
            app.X1 = uieditfield(app.Circuit1, 'numeric');
            app.X1.ValueChangedFcn = createCallbackFcn(app, @X1_1Changed, true);
            app.X1.Value = -6.1;
            app.YEditField_2Label = uilabel(app.Circuit1);
            app.YEditField_2Label.Text = 'Y';
            app.Y1 = uieditfield(app.Circuit1, 'numeric');
            app.Y1.ValueChangedFcn = createCallbackFcn(app, @Y1_1Changed, true);
            app.Y1.Value = 12;
            app.Conductor1Label = uilabel(app.Circuit1);
            app.Conductor1Label.Text = 'Conductor 1';
            app.Conductor2Label = uilabel(app.Circuit1);
            app.Conductor2Label.Text = 'Conductor 2';
            app.X_A_2Label = uilabel(app.Circuit1);
            app.X_A_2Label.Text = 'X';
            app.X2 = uieditfield(app.Circuit1, 'numeric');
            app.X2.ValueChangedFcn = createCallbackFcn(app, @X1_2Changed, true);
            app.X2.Value = -5.7;
            app.Y_A_2Label = uilabel(app.Circuit1);
            app.Y_A_2Label.Text = 'Y';
            app.Y2 = uieditfield(app.Circuit1, 'numeric');
            app.Y2.ValueChangedFcn = createCallbackFcn(app, @Y1_2Changed, true);
            app.Y2.Value = 19.8;
            app.X2_2Label = uilabel(app.Circuit1);
            app.X2_2Label.Text = 'X';
            app.X3 = uieditfield(app.Circuit1, 'numeric');
            app.X3.ValueChangedFcn = createCallbackFcn(app, @X1_3Changed, true);
            app.X3.Value = -5.4;
            app.Conductor3Label = uilabel(app.Circuit1);
            app.Conductor3Label.Text = 'Conductor 3';
            app.Y2_2Label = uilabel(app.Circuit1);
            app.Y2_2Label.Text = 'Y';
            app.Y3 = uieditfield(app.Circuit1, 'numeric');
            app.Y3.ValueChangedFcn = createCallbackFcn(app, @Y1_3Changed, true);
            app.Y3.Value = 27.6;
            app.LineCurrentEditField_2Label = uilabel(app.Circuit1);
            app.LineCurrentEditField_2Label.Text = 'Line Current';
            app.LineCurrent1 = uieditfield(app.Circuit1, 'numeric');
            app.LineCurrent1.Value = 350;
            app.CalculateButton = uibutton(app.InputsTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MetersfromCenterlineEditFieldLabel = uilabel(app.InputsTab);
            app.MetersfromCenterlineEditFieldLabel.Text = 'Meters from Centerline';
            app.HorizontalRange = uieditfield(app.InputsTab, 'numeric');
            app.HorizontalRange.Limits = [0 Inf];
            app.HorizontalRange.Value = 20;
            app.Circuit2 = uipanel(app.InputsTab);
            app.Circuit2.Title = 'Circuit Two';
            app.LineCurrentA_2Label = uilabel(app.Circuit2);
            app.LineCurrentA_2Label.Text = 'Line Current';
            app.LineCurrent2 = uieditfield(app.Circuit2, 'numeric');
            app.LineCurrent2.Value = 350;
            app.X1_2Label = uilabel(app.Circuit2);
            app.X1_2Label.Text = 'X';
            app.X1_2 = uieditfield(app.Circuit2, 'numeric');
            app.X1_2.ValueChangedFcn = createCallbackFcn(app, @X2_1Changed, true);
            app.X1_2.Value = 6.1;
            app.Y1_2Label = uilabel(app.Circuit2);
            app.Y1_2Label.Text = 'Y';
            app.Y1_2 = uieditfield(app.Circuit2, 'numeric');
            app.Y1_2.ValueChangedFcn = createCallbackFcn(app, @Y2_1Changed, true);
            app.Y1_2.Value = 12;
            app.Conductor1Label_2 = uilabel(app.Circuit2);
            app.Conductor1Label_2.Text = 'Conductor 1';
            app.Conductor2Label_2 = uilabel(app.Circuit2);
            app.Conductor2Label_2.Text = 'Conductor 2';
            app.X2_2Label_2 = uilabel(app.Circuit2);
            app.X2_2Label_2.Text = 'X';
            app.X2_2 = uieditfield(app.Circuit2, 'numeric');
            app.X2_2.ValueChangedFcn = createCallbackFcn(app, @X2_2Changed, true);
            app.X2_2.Value = 5.7;
            app.Y2_2Label_2 = uilabel(app.Circuit2);
            app.Y2_2Label_2.Text = 'Y';
            app.Y2_2 = uieditfield(app.Circuit2, 'numeric');
            app.Y2_2.ValueChangedFcn = createCallbackFcn(app, @Y2_2Changed, true);
            app.Y2_2.Value = 19.8;
            app.X3_2Label = uilabel(app.Circuit2);
            app.X3_2Label.Text = 'X';
            app.X3_2 = uieditfield(app.Circuit2, 'numeric');
            app.X3_2.ValueChangedFcn = createCallbackFcn(app, @X2_3Changed, true);
            app.X3_2.Value = 5.4;
            app.Conductor3Label_2 = uilabel(app.Circuit2);
            app.Conductor3Label_2.Text = 'Conductor 3';
            app.Y3_2Label = uilabel(app.Circuit2);
            app.Y3_2Label.Text = 'Y';
            app.Y3_2 = uieditfield(app.Circuit2, 'numeric');
            app.Y3_2.ValueChangedFcn = createCallbackFcn(app, @Y2_3Changed, true);
            app.Y3_2.Value = 27.6;
            app.CircuitTwoEnabled = uicheckbox(app.Circuit2);
            app.CircuitTwoEnabled.ValueChangedFcn = createCallbackFcn(app, @CircuitTwoEnabledChanged, true);
            app.CircuitTwoEnabled.Text = 'Enable Circuit Two';
            app.CircuitTwoEnabled.Value = true;
            app.TowerTypeDropDownLabel = uilabel(app.InputsTab);
            app.TowerTypeDropDownLabel.Text = 'Tower Type';
            app.TowerTypeDropDown = uidropdown(app.InputsTab);
            app.TowerTypeDropDown.Items = {'240kV Type L No Extension', '138kV Tangent 19m pole', 'Custom'};
            app.TowerTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TowerTypeChanged, true);
            app.TowerTypeDropDown.Value = '240kV Type L No Extension';
            app.PhasingDropDownLabel = uilabel(app.InputsTab);
            app.PhasingDropDownLabel.Text = 'Phasing';
            app.PhasingDropDown = uidropdown(app.InputsTab);
            app.PhasingDropDown.Items = {'rgb - rgb', 'rgb - bgr', 'rgb - rbg', 'rgb - brg', 'rgb - gbr', 'rgb - grb'};
            app.PhasingDropDown.ValueChangedFcn = createCallbackFcn(app, @PhasingChanged, true);
            app.PhasingDropDown.Value = 'rgb - rgb';
            app.HeightAboveGroundEditFieldLabel = uilabel(app.InputsTab);
            app.HeightAboveGroundEditFieldLabel.Text = 'Height Above Ground';
            app.Height = uieditfield(app.InputsTab, 'numeric');
            app.Height.ValueChangedFcn = createCallbackFcn(app, @HeightAboveGroudChanged, true);
            app.TowerConfigGraph = uiaxes(app.InputsTab);
            title(app.TowerConfigGraph, 'Tower Configuration');
            xlabel(app.TowerConfigGraph, 'Horizontal Dist. (m)');
            ylabel(app.TowerConfigGraph, 'Vertical Dist. (m)');
            app.TowerConfigGraph.XLim = [-10 10];
            app.TowerConfigGraph.XLimMode = 'manual';
            app.TowerConfigGraph.YLim = [0 40];
            app.TowerConfigGraph.YLimMode = 'manual';
            app.MSDEditFieldLabel = uilabel(app.InputsTab);
            app.MSDEditFieldLabel.Text = 'MSD';
            app.MSD = uieditfield(app.InputsTab, 'numeric');
            app.MSD.Value = 5;
            app.MagFieldTab = uitab(app.TabGroup);
            app.MagFieldTab.Title = 'Mag Field';
            app.MagFieldTab.Units = 'pixels';
            app.MagneticFluxGraph = uiaxes(app.MagFieldTab);
            title(app.MagneticFluxGraph, 'Magnitude of Magnetic Flux Density From Transmission Line');
            xlabel(app.MagneticFluxGraph, 'Horizontal Distance (m)');
            ylabel(app.MagneticFluxGraph, 'Magnetic Flux Magnitude (T)');
            app.MinimumSafeDistanceTab = uitab(app.TabGroup);
            app.MinimumSafeDistanceTab.Title = 'Minimum Safe Distance';
            app.MinimumSafeDistanceTab.Units = 'pixels';
            app.MinimumMagFieldCrossSection = uiaxes(app.MinimumSafeDistanceTab);
            title(app.MinimumMagFieldCrossSection, 'Magnetic Minimum Safe Distance Boundary');
            xlabel(app.MinimumMagFieldCrossSection, 'Horizontal Dist. (m)');
            ylabel(app.MinimumMagFieldCrossSection, 'Vertical Dist. (m)');
            app.MinimumMagFieldCrossSection.XLim = [-20 20];
            app.MinimumMagFieldCrossSection.XLimMode = 'manual';
            app.MinimumMagFieldCrossSection.YLim = [10 40];
            app.MinimumMagFieldCrossSection.YLimMode = 'manual';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MagneticFieldCalculator()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end